classdef AppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Carrega             matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        PainelEmpresas      matlab.ui.container.Panel
        Espaco1             matlab.ui.control.CheckBox
        Espaco2             matlab.ui.control.CheckBox
        Espaco3             matlab.ui.control.CheckBox
        Espaco4             matlab.ui.control.CheckBox
        Espaco5             matlab.ui.control.CheckBox
        Espaco6             matlab.ui.control.CheckBox
        Espaco7             matlab.ui.control.CheckBox
        Espaco8             matlab.ui.control.CheckBox
        Espaco9             matlab.ui.control.CheckBox
        Espaco10            matlab.ui.control.CheckBox
        SelecionaIndicador  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Objeto;
    end
    

    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            
        end

        % Button pushed function: Carrega
        function CarregaButtonPushed(app, event)
            
            %% Chama a função principal.
            Main;
            
            %% Quantidade de arquivos carregados.
            QuantidadeArquivos = max(size(o));
            
            %% Valida os espaços vazios e coloca os nomes das empresas carregadas
            if (QuantidadeArquivos >= 1)
                app.Espaco1.Enable = 'on';
                app.Espaco1.Text = o(1).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 2)
                app.Espaco2.Enable = 'on';
                app.Espaco2.Text = o(2).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 3)
                app.Espaco3.Enable = 'on';
                app.Espaco3.Text = o(3).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 4)
                app.Espaco4.Enable = 'on';
                app.Espaco4.Text = o(4).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 5)
                app.Espaco5.Enable = 'on';
                app.Espaco5.Text = o(5).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 6)
                app.Espaco6.Enable = 'on';
                app.Espaco6.Text = o(6).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 7)
                app.Espaco7.Enable = 'on';
                app.Espaco7.Text = o(7).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 8)
                app.Espaco8.Enable = 'on';
                app.Espaco8.Text = o(8).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 9)
                app.Espaco9.Enable = 'on';
                app.Espaco9.Text = o(9).NomeEmpresa;
            end
            if (QuantidadeArquivos >= 10)
                app.Espaco10.Enable = 'on';
                app.Espaco10.Text = o(10).NomeEmpresa;
            end
                        
            %% Ativando o painel das empresas.
            app.SelecionaIndicador.Enable = 'on';
            
            %% Salva o valor recém carregado de 'o' em uma 'Property'.
            app.Objeto = o;
        end

        % Size changed function: PainelEmpresas
        function PainelEmpresasSizeChanged(app, event)
            position = app.PainelEmpresas.Position;
        end

        % Value changed function: SelecionaIndicador
        function SelecionaIndicadorValueChanged(app, event)
            %% Qual o indicador que está sendo chamado?
            Indicador = app.SelecionaIndicador.Value;
                        
            %% Pega o valor do objeto novamente.
            o = app.Objeto;
            
            %% Verifica se a referida empresa está selecionada para análise.
            if(app.Espaco1.HandleVisibility == 'on')
                if(app.Espaco1.Value == 1)
                    a(1) = 1;
                else
                    a(1) = 0;
                end
            else
                a(1) = 0;
            end
            
            if(app.Espaco2.HandleVisibility == 'on')
                if(app.Espaco2.Value == 1)
                    a(2) = 1;
                else
                    a(2) = 0;
                end
            else
                a(2) = 0;
            end
            
            if(app.Espaco3.HandleVisibility == 'on')
                if(app.Espaco3.Value == 1)
                    a(3) = 1;
                else
                    a(3) = 0;
                end
            else
                a(3) = 0;
            end
            
            if(app.Espaco4.HandleVisibility == 'on')
                if(app.Espaco4.Value == 1)
                    a(4) = 1;
                else
                    a(4) = 0;
                end
            else
                a(4) = 0;
            end
            
            if(app.Espaco5.HandleVisibility == 'on')
                if(app.Espaco5.Value == 1)
                    a(5) = 1;
                else
                    a(5) = 0;
                end
            else
                a(5) = 0;
            end
            
            if(app.Espaco6.HandleVisibility == 'on')
                if(app.Espaco6.Value == 1)
                    a(6) = 1;
                else
                    a(6) = 0;
                end
            else
                a(6) = 0;
            end
            
            if(app.Espaco7.HandleVisibility == 'on')
                if(app.Espaco7.Value == 1)
                    a(7) = 1;
                else
                    a(7) = 0;
                end
            else
                a(7) = 0;
            end
            
            if(app.Espaco8.HandleVisibility == 'on')
                if(app.Espaco8.Value == 1)
                    a(8) = 1;
                else
                    a(8) = 0;
                end
            else
                a(8) = 0;
            end
            
            if(app.Espaco9.HandleVisibility == 'on')
                if(app.Espaco9.Value == 1)
                    a(9) = 1;
                else
                    a(9) = 0;
                end
            else
                a(9) = 0;
            end
            
            if(app.Espaco10.HandleVisibility == 'on')
                if(app.Espaco10.Value == 1)
                    a(10) = 1;
                else
                    a(10) = 0;
                end
            else
                a(10) = 0;
            end
            
            
            %% Definição de cores para plot.
            Cor = ['b' 'g' 'r' 'c' 'm' 'y' 'k' 'b:' 'g:' 'r:' 'c:'];
            
            for i = 1:10
                
                if(a(i) == 1)
                    GrossuraLinha = 2;
                    TamanhoFonte  = 12;
                    
                    switch Indicador
                        
                        % ROE
                        case 'ROE'
                            x = o(i).Indicador.ROE.x;
                            y = o(i).Indicador.ROE.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha);
                            title(app.UIAxes,'ROE - Retorno sobre o Patrimônio Líquido', 'FontSize', TamanhoFonte);
                            xlabel(app.UIAxes, 'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes,'%', 'FontSize', TamanhoFonte);
                            grid(app.UIAxes, 'on');
%                             legend(app.UIAxes,)
                            
                        % Índice de Liquidez Corrente
                        case 'Índice de Liquidez Corrente'
                            x = o(i).Indicador.IndiceLiquidezCorrente.x;
                            y = o(i).Indicador.IndiceLiquidezCorrente.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha); 
                            title(app.UIAxes,'Índice de Liquidez Corrente', 'FontSize',TamanhoFonte);
                            xlabel(app.UIAxes,'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes,'');    
                            grid(app.UIAxes, 'on');
                    
                        % Lucro Líquido 
                        case 'Lucro Líquido (Trimestre)'          
                            x = o(i).Indicador.LucroLiquido.Trimestre.x;
                            y = o(i).Indicador.LucroLiquido.Trimestre.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha);
                            title(app.UIAxes,'Lucro Líquido (Trimestre)', 'FontSize', TamanhoFonte);
                            xlabel(app.UIAxes,'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes,'Valor em milhares de R$', 'FontSize', TamanhoFonte);
                            grid(app.UIAxes, 'on');
            
                        case 'Lucro Líquido (Ano)'          
                            x = o(i).Indicador.LucroLiquido.Ano.x;
                            y = o(i).Indicador.LucroLiquido.Ano.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha);
                            title(app.UIAxes,'Lucro Líquido (Ano)', 'FontSize', TamanhoFonte);
                            xlabel(app.UIAxes,'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes,'Valor em milhares de R$', 'FontSize', TamanhoFonte); 
                            grid(app.UIAxes, 'on');
                            
                        % Margem Líquida
                        case 'Margem Líquida'
                            x = o(i).Indicador.MargemLiquida.x;
                            y = o(i).Indicador.MargemLiquida.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha);
                            title(app.UIAxes,'Margem Líquida', 'FontSize', TamanhoFonte);
                            xlabel(app.UIAxes,'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes,'%', 'FontSize',TamanhoFonte);
                            grid(app.UIAxes, 'on');
                            
                        % Margem Bruta
                        case 'Margem Bruta'
                            x = o(i).Indicador.MargemBruta.x;
                            y = o(i).Indicador.MargemBruta.y;
                            plot(app.UIAxes, x, y, Cor(i), 'LineWidth', GrossuraLinha); 
                            title(app.UIAxes, 'Margem Bruta', 'FontSize', TamanhoFonte);
                            xlabel(app.UIAxes, 'Ano', 'FontSize', TamanhoFonte);
                            ylabel(app.UIAxes, '%', 'FontSize', TamanhoFonte); 
                            grid(app.UIAxes, 'on');
            
                    end
                    hold(app.UIAxes,'on');
                end
                
                if (i == 10)
                    hold(app.UIAxes,'off');
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Carrega = uibutton(app.UIFigure, 'push');
            app.Carrega.ButtonPushedFcn = createCallbackFcn(app, @CarregaButtonPushed, true);
            app.Carrega.Text = 'Carrega Arquivos';
            app.UIAxes = uiaxes(app.UIFigure);
            app.PainelEmpresas = uipanel(app.UIFigure);
            app.PainelEmpresas.Title = 'Empresas';
            app.PainelEmpresas.SizeChangedFcn = createCallbackFcn(app, @PainelEmpresasSizeChanged, true);
            app.Espaco1 = uicheckbox(app.PainelEmpresas);
            app.Espaco1.Enable = 'off';
            app.Espaco1.Text = '';
            app.Espaco2 = uicheckbox(app.PainelEmpresas);
            app.Espaco2.Enable = 'off';
            app.Espaco2.Text = '';
            app.Espaco3 = uicheckbox(app.PainelEmpresas);
            app.Espaco3.Enable = 'off';
            app.Espaco3.Text = '';
            app.Espaco4 = uicheckbox(app.PainelEmpresas);
            app.Espaco4.Enable = 'off';
            app.Espaco4.Text = '';
            app.Espaco5 = uicheckbox(app.PainelEmpresas);
            app.Espaco5.Enable = 'off';
            app.Espaco5.Text = '';
            app.Espaco6 = uicheckbox(app.PainelEmpresas);
            app.Espaco6.Enable = 'off';
            app.Espaco6.Text = '';
            app.Espaco7 = uicheckbox(app.PainelEmpresas);
            app.Espaco7.Enable = 'off';
            app.Espaco7.Text = '';
            app.Espaco8 = uicheckbox(app.PainelEmpresas);
            app.Espaco8.Enable = 'off';
            app.Espaco8.Text = '';
            app.Espaco9 = uicheckbox(app.PainelEmpresas);
            app.Espaco9.Enable = 'off';
            app.Espaco9.Text = '';
            app.Espaco10 = uicheckbox(app.PainelEmpresas);
            app.Espaco10.Enable = 'off';
            app.Espaco10.Text = '';
            app.SelecionaIndicador = uidropdown(app.UIFigure);
            app.SelecionaIndicador.Items = {'ROE', 'Índice de Liquidez Corrente', 'Lucro Líquido (Trimestre)', 'Lucro Líquido (Ano)', 'Margem Líquida', 'Margem Bruta'};
            app.SelecionaIndicador.ValueChangedFcn = createCallbackFcn(app, @SelecionaIndicadorValueChanged, true);
            app.SelecionaIndicador.Enable = 'off';
            app.SelecionaIndicador.Value = 'ROE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppAnalise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end